classdef Prey_predator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        LoadDataLabel           matlab.ui.control.Label
        designLabel1            matlab.ui.control.Label
        parameterC              matlab.ui.control.Slider
        Slider_2Label_2         matlab.ui.control.Label
        parameterB              matlab.ui.control.Slider
        Label_4                 matlab.ui.control.Label
        designLabel2            matlab.ui.control.Label
        PreypredatormodelLabel  matlab.ui.control.Label
        LoadData                matlab.ui.control.Label
        Label_FileName          matlab.ui.control.Label
        Files                   matlab.ui.control.DropDown
        SheetnameLabel          matlab.ui.control.Label
        SaveFileName            matlab.ui.control.EditField
        Label_3                 matlab.ui.control.Label
        SaveData                matlab.ui.control.Button
        F0                      matlab.ui.control.Knob
        TheinitialcarnivorousspeciespopulationLabel  matlab.ui.control.Label
        R0                      matlab.ui.control.Knob
        Label                   matlab.ui.control.Label
        resetButton             matlab.ui.control.Button
        parameterS              matlab.ui.control.Slider
        ConstantlimitstherateofpredationLabel  matlab.ui.control.Label
        parameterK              matlab.ui.control.Slider
        ConstantlimitsthepopulationofthepreyspeciesLabel  matlab.ui.control.Label
        parameterD              matlab.ui.control.Slider
        Label_6                 matlab.ui.control.Label
        PreydatorCheckBox       matlab.ui.control.CheckBox
        PreyCheckBox            matlab.ui.control.CheckBox
        parameterA              matlab.ui.control.Slider
        Label_2                 matlab.ui.control.Label
        PreyPredatorAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        plot1 % prey plot
        plot2 % predator plot
        preySol % prey solution
        predatorSol %predator solution
        saveTime    % time vector
    end
    
    methods (Access = public)
        function [] = calcSol(app)
             Tf = 100; % simulation length
             %u prey,v predator
             %parameters
             u0=app.R0.Value;
             v0=app.F0.Value;
             a=app.parameterA.Value;
             k=app.parameterK.Value;
             s=app.parameterS.Value;
             b=app.parameterB.Value;
             c=app.parameterC.Value;
             d=app.parameterD.Value;
             options = odeset('RelTol',1e-8);
             PP0 = [u0 v0];
             params = [a k b s c d];
             %call ode 45 to solve ordinary differential equation
             [t,sol] = ode45(@calcdsol,[0 Tf],PP0,options,params);
             u = sol(:,1);
             app.preySol=u;
             %plot
             hold(app.PreyPredatorAxes,'off');
             app.plot1=plot(app.PreyPredatorAxes,t,u);
             hold(app.PreyPredatorAxes,'on');
             grid(app.PreyPredatorAxes,'on');
             v = sol(:,2);
             app.predatorSol=v;
             app.plot2=plot(app.PreyPredatorAxes,t,v);
             app.plot1.Visible=app.PreyCheckBox.Value;
             app.plot2.Visible=app.PreydatorCheckBox.Value;
             app.saveTime=t;
             legend(app.PreyPredatorAxes,'Prey','Predator');
             app.Label_FileName.Text="";
             app.LoadDataLabel.Text="";
        end 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: parameterA
        function parameterAValueChanging(app, event)
            calcSol(app);
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
           app.F0.Value=400;
           app.R0.Value=600;
           app.parameterA.Value=0;
           app.parameterD.Value=0;
           app.parameterK.Value=0;
           app.parameterS.Value=0;
           app.parameterB.Value=0;
           app.parameterC.Value=0;
           app.PreyCheckBox.Value=true;
           app.PreydatorCheckBox.Value=true;
           calcSol(app);
           app.Label_FileName.Text="";
           app.PreyCheckBox.Value=false;
           app.PreydatorCheckBox.Value=false;
           app.SaveFileName.Value="";
           app.LoadDataLabel.Text="";
        end

        % Value changed function: PreyCheckBox
        function PreyCheckBoxValueChanged(app, event)
            value = app.PreyCheckBox.Value;
            app.plot1.Visible=value;
        end

        % Value changed function: PreydatorCheckBox
        function PreydatorCheckBoxValueChanged(app, event)
            value = app.PreydatorCheckBox.Value;
            app.plot2.Visible=value;
        end

        % Value changing function: parameterD
        function parameterDValueChanging(app, event)
             calcSol(app);
        end

        % Value changing function: parameterK
        function parameterKValueChanging(app, event)
             calcSol(app);
        end

        % Value changing function: parameterS
        function parameterSValueChanging(app, event)
             calcSol(app);
        end

        % Value changing function: F0
        function F0ValueChanging(app, event)
            calcSol(app);
        end

        % Value changing function: R0
        function R0ValueChanging(app, event)
            calcSol(app);
        end

        % Button pushed function: SaveData
        function SaveDataButtonPushed(app, event)
            filename='Data.xlsx';
            params=zeros(length(app.preySol),1);
            params(1)=app.parameterA.Value;
            params(2)=app.parameterK.Value;
            params(3)=app.parameterB.Value;
            params(4)=app.parameterS.Value;
            params(5)=app.parameterC.Value;
            params(6)=app.parameterD.Value;
            params(7)=app.F0.Value;
            params(8)= app.R0.Value;
             params(9:end)=nan;
             T=table(app.preySol,app.predatorSol,app.saveTime,params);
            T.Properties.VariableNames(1:4) = {'Prey','Predator','Time','Parameters'};
            str="";
            str=app.SaveFileName.Value;
            if (str == "")
                app.Label_FileName.Text='Please enter file name';
            else
            writetable(T,filename,'sheet',str);
            app.Label_FileName.Text="Data saved successfully";
            end
        end

        % Drop down opening function: Files
        function FilesDropDownOpening(app, event)
            filename='Data.xlsx';
            sheets=sheetnames(filename);
            app.Files.Items=sheets;
        end

        % Value changed function: Files
        function FilesValueChanged(app, event)
             value = app.Files.Value;
             filename='Data.xlsx';
             T=readtable(filename,'Sheet',value);
             prey1=T(:,1);
             prey=table2array(prey1);
             predator1=T(:,2);
             predator=table2array(predator1);
             t1=T(:,3);
             t=table2array(t1);
             hold(app.PreyPredatorAxes,'off');
             app.plot1=plot(app.PreyPredatorAxes,t,prey);
             hold(app.PreyPredatorAxes,'on');
             grid(app.PreyPredatorAxes,'on');
             app.plot2=plot(app.PreyPredatorAxes,t,predator);
             app.plot1.Visible=app.PreyCheckBox.Value;
             app.plot2.Visible=app.PreydatorCheckBox.Value;
             legend(app.PreyPredatorAxes,'Prey','Predator');
             app.parameterA.Value=table2array(T(1,4));
             app.parameterK.Value=table2array(T(2,4));
             app.parameterB.Value=table2array(T(3,4));
             app.parameterS.Value=table2array(T(4,4));
             app.parameterC.Value=table2array(T(5,4));
             app.parameterD.Value=table2array(T(6,4));
             app.F0.Value=table2array(T(7,4));
             app.R0.Value=table2array(T(8,4));
             app.LoadDataLabel.Text="Data loaded successfully";
             app.Label_FileName.Text="";
        end

        % Value changing function: parameterB
        function parameterBValueChanging(app, event)
                calcSol(app);
        end

        % Value changing function: parameterC
        function parameterCValueChanging(app, event)
                     calcSol(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PreyPredatorAxes = uiaxes(app.UIFigure);
            title(app.PreyPredatorAxes, 'Predators & Preys vs time')
            xlabel(app.PreyPredatorAxes, 'Time')
            ylabel(app.PreyPredatorAxes, 'Density')
            zlabel(app.PreyPredatorAxes, 'Z')
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'Prey species population growth rate'; '(קצב הגידול של אוכלוסיית המין הנטרף)'};
            app.parameterA = uislider(app.UIFigure);
            app.parameterA.Limits = [0 1];
            app.parameterA.ValueChangingFcn = createCallbackFcn(app, @parameterAValueChanging, true);
            app.PreyCheckBox = uicheckbox(app.UIFigure);
            app.PreyCheckBox.ValueChangedFcn = createCallbackFcn(app, @PreyCheckBoxValueChanged, true);
            app.PreyCheckBox.Text = 'Prey';
            app.PreydatorCheckBox = uicheckbox(app.UIFigure);
            app.PreydatorCheckBox.ValueChangedFcn = createCallbackFcn(app, @PreydatorCheckBoxValueChanged, true);
            app.PreydatorCheckBox.Text = {'Preydator'; ''};
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = {'Constant disappearance rate of the predator species'; '(קבוע קצב ההעלמות של המין הטורף)'};
            app.parameterD = uislider(app.UIFigure);
            app.parameterD.Limits = [0 1];
            app.parameterD.ValueChangingFcn = createCallbackFcn(app, @parameterDValueChanging, true);
            app.ConstantlimitsthepopulationofthepreyspeciesLabel = uilabel(app.UIFigure);
            app.ConstantlimitsthepopulationofthepreyspeciesLabel.Text = {'Constant limits the population of the prey species'; '(קבוע המגביל את אוכלוסיית המין הנטרף)'};
            app.parameterK = uislider(app.UIFigure);
            app.parameterK.Limits = [0 800];
            app.parameterK.MajorTicks = [0 160 400 600 800];
            app.parameterK.ValueChangingFcn = createCallbackFcn(app, @parameterKValueChanging, true);
            app.ConstantlimitstherateofpredationLabel = uilabel(app.UIFigure);
            app.ConstantlimitstherateofpredationLabel.Text = {'Constant limits the rate of predation'; '(קבוע המגביל את קצב הטריפה)'};
            app.parameterS = uislider(app.UIFigure);
            app.parameterS.Limits = [0 800];
            app.parameterS.ValueChangingFcn = createCallbackFcn(app, @parameterSValueChanging, true);
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Initial prey species population'; '(אוכלוסיית המין הנטרף ההתחלתית)'};
            app.R0 = uiknob(app.UIFigure, 'continuous');
            app.R0.Limits = [600 1600];
            app.R0.ValueChangingFcn = createCallbackFcn(app, @R0ValueChanging, true);
            app.R0.Value = 600;
            app.TheinitialcarnivorousspeciespopulationLabel = uilabel(app.UIFigure);
            app.TheinitialcarnivorousspeciespopulationLabel.Text = {'Initial predator species population'; '(אוכלוסיית המין הטורף ההתחלתית)'};
            app.F0 = uiknob(app.UIFigure, 'continuous');
            app.F0.Limits = [400 1100];
            app.F0.ValueChangingFcn = createCallbackFcn(app, @F0ValueChanging, true);
            app.F0.Value = 400;
            app.SaveData = uibutton(app.UIFigure, 'push');
            app.SaveData.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveData.Text = 'Save data';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Save as:';
            app.SaveFileName = uieditfield(app.UIFigure, 'text');
            app.SheetnameLabel = uilabel(app.UIFigure);
            app.SheetnameLabel.Text = 'Sheet name';
            app.Files = uidropdown(app.UIFigure);
            app.Files.Items = {};
            app.Files.DropDownOpeningFcn = createCallbackFcn(app, @FilesDropDownOpening, true);
            app.Files.ValueChangedFcn = createCallbackFcn(app, @FilesValueChanged, true);
            app.Files.Value = {};
            app.Label_FileName = uilabel(app.UIFigure);
            app.Label_FileName.Text = '';
            app.LoadData = uilabel(app.UIFigure);
            app.LoadData.Text = 'Select sheet to load data';
            app.PreypredatormodelLabel = uilabel(app.UIFigure);
            app.PreypredatormodelLabel.Text = 'Prey - predator model';
            app.designLabel2 = uilabel(app.UIFigure);
            app.designLabel2.Text = '__________________________________________________________________________________________________________________________________________________';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = {'Disappearance rate of the prey species population'; '(קצב ההעלמות של אוכלוסיית המין הנטרף)'};
            app.parameterB = uislider(app.UIFigure);
            app.parameterB.Limits = [0 0.005];
            app.parameterB.ValueChangingFcn = createCallbackFcn(app, @parameterBValueChanging, true);
            app.Slider_2Label_2 = uilabel(app.UIFigure);
            app.Slider_2Label_2.Text = {'Predator species population growth rate'; '(קצב הגידול של אוכלוסיית המין הטורף)'};
            app.parameterC = uislider(app.UIFigure);
            app.parameterC.Limits = [0 0.005];
            app.parameterC.ValueChangingFcn = createCallbackFcn(app, @parameterCValueChanging, true);
            app.designLabel1 = uilabel(app.UIFigure);
            app.designLabel1.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.LoadDataLabel = uilabel(app.UIFigure);
            app.LoadDataLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Prey_predator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end